classdef zia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        PlateletCounterLabel  matlab.ui.control.Label
        TabGroup              matlab.ui.container.TabGroup
        Step1Tab              matlab.ui.container.Tab
        Step1Panel            matlab.ui.container.Panel
        LoadImageButton       matlab.ui.control.Button
        PreprocessingButton   matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        Step2Tab              matlab.ui.container.Tab
        WBCPanel              matlab.ui.container.Panel
        SegmentWBCButton      matlab.ui.control.Button
        CountWBCButton        matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        WBCCount              matlab.ui.control.NumericEditField
        RBCPanel              matlab.ui.container.Panel
        SegmentRBCButton      matlab.ui.control.Button
        CountRBCButton        matlab.ui.control.Button
        EditField_2Label      matlab.ui.control.Label
        RBCcount              matlab.ui.control.NumericEditField
        mask                  matlab.ui.control.UIAxes
        segmented             matlab.ui.control.UIAxes
        AboutTab              matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Input;
        erodedI;
        outline;
        green;
        Segmented_RBC;
        a;
        b;
        c;
        d;
        WBC_Count;
    end


    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [FileName, PathName] = uigetfile('*.png','select an image');
            app.Input = imread(strcat(PathName,FileName));
            imshow(app.Input,'parent',app.UIAxes);
            app.a=1;
            app.UIAxes_2.cla;
            app.mask.cla;
            app.segmented.cla;
            app.WBCCount.Value = 0;
            app.RBCcount.Value = 0;
        end

        % Button pushed function: PreprocessingButton
        function PreprocessingButtonPushed(app, event)
            if app.a == 1
                I1 = rgb2gray(app.Input);
                I2 = adapthisteq(I1);
                
                BW = imbinarize(I2 , graythresh(I2));
                BW1 = bwareaopen(BW,1000);
                BW2 = ~BW1;
                
                BW3 = imfill(BW2, 'holes');
                BW4 = bwareaopen(BW3,400);
                
                se = strel('disk',5);
                app.erodedI = imerode(BW4,se);
                imshow(app.erodedI,'parent',app.UIAxes_2);
                title('Preprocessed Blood Sample','parent',app.UIAxes_2);
                subplot(2,2,1);
                imshow(I1);title('Gray image');
                subplot(2,2,2);
                imshow(I2);title('Contrast enhancjemnt');
                subplot(2,2,3);
                imshow(BW1);title('Removed small object');
                subplot(2,2,4);
                imshow(app.erodedI);title('Inverted binary image');
                app.b=1;
            else
                msgbox('First Load Blood Sample','Error','error');
            end
            
        end

        % Button pushed function: SegmentWBCButton
        function SegmentWBCButtonPushed(app, event)
            if app.b==1
                Segmented_WBC = bwareafilt(app.erodedI,[2000 50000]);
                Segmented_WBC = imfill(Segmented_WBC,'holes');
                imshow(Segmented_WBC,'parent',app.mask);
                title('WBC Segmented Binary mask' , 'parent',app.mask);
                
                app.outline = bwperim(Segmented_WBC);
                app.green = zeros(size(app.Input,1),size(app.Input,2),3);
                app.green(:,:,2)=1;
                imshow(app.Input,'parent',app.segmented);
                hold(app.segmented,'on');
                title('WBC Mask overley on Blood sample', 'parent',app.segmented);
                se = strel('disk',7);
                Segmented_WBC = imerode(Segmented_WBC,se);
                hold(app.segmented,'on');
                h=imshow(app.green,'parent',app.segmented);
                set(h,'AlphaData',app.outline);
                
                cc = bwconncomp(Segmented_WBC);
                app.WBC_Count = cc.NumObjects;
                app.c=1;
            else
                msgbox('First load blood sample and process','Error','error');
            end
            
        end

        % Button pushed function: CountWBCButton
        function CountWBCButtonPushed(app, event)

            if app.c ==1
                app.WBCCount.Value = app.WBC_Count;
            else
                msgbox('First segment WBC','Error' , 'error');
            end
        end

        % Callback function
        function SegmentRBCButtonPushed(app, event)
            if app.b==1
                app.Segmented_RBC = bwareafilt(app.erodedI,[400 6400]);
                
                
                app.Segmented_RBC = imfill(app.Segmented_RBC,'holes');
                imshow(app.Segmented_RBC , 'parent',app.mask);
                
                app.outline = bwperin(app.Segmented_RBC);
                app.green = zeros(size(app.Input,1),size(app.Input,2),3);
                app.green(:,:,2)=1;
                imshow(app.Input,'parent',app.segmented);
                hold(app.segmented,'on');
                h=imshow(app.green,'parent',app.segmented);
                set(h,'AlphaData',app.outline);
                app.d=1;
            else
                msgbox('First Load blood sample & preprocess','ERROR','error');
            end
        end

        % Size changed function: Step2Tab
        function Step2TabSizeChanged(app, event)
            position = app.Step2Tab.Position;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlateletCounterLabel = uilabel(app.UIFigure);
            app.PlateletCounterLabel.Text = 'Platelet Counter';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Step1Tab = uitab(app.TabGroup);
            app.Step1Tab.Title = 'Step1';
            app.Step1Panel = uipanel(app.Step1Tab);
            app.Step1Panel.Title = 'Step1';
            app.LoadImageButton = uibutton(app.Step1Panel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.PreprocessingButton = uibutton(app.Step1Panel, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
            app.PreprocessingButton.Text = 'Preprocessing';
            app.UIAxes = uiaxes(app.Step1Tab);
            app.UIAxes_2 = uiaxes(app.Step1Tab);
            app.Step2Tab = uitab(app.TabGroup);
            app.Step2Tab.SizeChangedFcn = createCallbackFcn(app, @Step2TabSizeChanged, true);
            app.Step2Tab.Title = 'Step2';
            app.WBCPanel = uipanel(app.Step2Tab);
            app.WBCPanel.Title = 'WBC';
            app.SegmentWBCButton = uibutton(app.WBCPanel, 'push');
            app.SegmentWBCButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentWBCButtonPushed, true);
            app.SegmentWBCButton.Text = 'Segment WBC';
            app.CountWBCButton = uibutton(app.WBCPanel, 'push');
            app.CountWBCButton.ButtonPushedFcn = createCallbackFcn(app, @CountWBCButtonPushed, true);
            app.CountWBCButton.Text = 'Count WBC';
            app.Label = uilabel(app.WBCPanel);
            app.Label.Text = '';
            app.WBCCount = uieditfield(app.WBCPanel, 'numeric');
            app.RBCPanel = uipanel(app.Step2Tab);
            app.RBCPanel.Title = 'RBC';
            app.SegmentRBCButton = uibutton(app.RBCPanel, 'push');
            app.SegmentRBCButton.Text = 'Segment RBC';
            app.CountRBCButton = uibutton(app.RBCPanel, 'push');
            app.CountRBCButton.Text = 'Count RBC';
            app.EditField_2Label = uilabel(app.RBCPanel);
            app.EditField_2Label.Text = '';
            app.RBCcount = uieditfield(app.RBCPanel, 'numeric');
            app.mask = uiaxes(app.Step2Tab);
            app.segmented = uiaxes(app.Step2Tab);
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
        end
    end

    methods (Access = public)

        % Construct app
        function app = zia

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end